classdef PLAcountApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ButtonGroupNucleichannel  matlab.ui.container.ButtonGroup
        BlueNucleiButton          matlab.ui.control.RadioButton
        GreenNucleiButton         matlab.ui.control.RadioButton
        RedNucleiButton           matlab.ui.control.RadioButton
        ButtonGroupPLAchannel     matlab.ui.container.ButtonGroup
        BluePLAButton             matlab.ui.control.RadioButton
        GreenPLAButton            matlab.ui.control.RadioButton
        RedPLAButton              matlab.ui.control.RadioButton
        LabelSlash                matlab.ui.control.Label
        LabelNumberOfImages       matlab.ui.control.Label
        Threshold3Slider          matlab.ui.control.Slider
        Threshold3SliderLabel     matlab.ui.control.Label
        Threshold2Slider          matlab.ui.control.Slider
        Threshold2SliderLabel     matlab.ui.control.Label
        Threshold1Slider          matlab.ui.control.Slider
        Threshold1SliderLabel     matlab.ui.control.Label
        ButtonExportData          matlab.ui.control.Button
        ButtonStart               matlab.ui.control.Button
        ButtonLoadImages          matlab.ui.control.Button
        NrSpinnerImages           matlab.ui.control.Spinner
        ImageNrSpinnerLabel       matlab.ui.control.Label
        ResultsPanel              matlab.ui.container.Panel
        AverageEditField          matlab.ui.control.NumericEditField
        AverageEditFieldLabel     matlab.ui.control.Label
        MedianEditField           matlab.ui.control.NumericEditField
        MedianEditFieldLabel      matlab.ui.control.Label
        NrcellsEditField          matlab.ui.control.NumericEditField
        NrcellsEditFieldLabel     matlab.ui.control.Label
        SwitchAdvancedMode        matlab.ui.control.Switch
        AdvancedmodeSwitchLabel   matlab.ui.control.Label
        LabelPLACount             matlab.ui.control.Label
        UIAxesImage               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % loaded images
        processedData % processed PLA images
        n % number of loaded images
        PLAdata % PLA data statistics
        vecPLA % PLA data statistics
        averagePLA
        cellCount
        medianPLA
        PLAChannel = 1;
        nucleiChannel = 3;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonLoadImages
        function ButtonLoadImagesPushed(app, event)
            [filename, pathname] = uigetfile( ...
            {  '*.jpg;*.jpeg;*.tif;*.png;*.gif;*.bmp','All Image Files';}, ...
               'Pick a PLA files', ...
               'MultiSelect', 'on');
            
            if isequal(filename,0)
                error('No files are loaded.');
            end
            
            if iscell(filename)==0 %cell check
                app.data = imread([pathname filename]); %load image
            else
                app.n = length(filename);
                app.data = struct([]); %memory allocation
                for i = 1:app.n
                    app.data{i} = imread([pathname filename{i}]); %load image
                end
            end

            if iscell(app.data) == 0 %if only one file is loaded
                app.n = 1;
                imgPLA = im2double(app.data); %get image
                app.NrSpinnerImages.Enable = 'off';
            else
                app.n = length(app.data); %if more files are loaded
                imgPLA = im2double(app.data{1}); %get image
                app.NrSpinnerImages.Enable = 'on';
                app.NrSpinnerImages.Limits = [1 app.n];
            end           
            imshow(imgPLA, 'parent', app.UIAxesImage)
            app.LabelNumberOfImages.Text = num2str(app.n);
            app.NrSpinnerImages.Value = 1;

            app.averagePLA = 0;
            app.medianPLA = 0;
            app.cellCount = 0;

            app.NrcellsEditField.Value = app.cellCount;
            app.MedianEditField.Value = app.medianPLA;
            app.AverageEditField.Value = app.averagePLA;
        end

        % Value changed function: NrSpinnerImages
        function NrSpinnerImagesValueChanged(app, event)
            value = app.NrSpinnerImages.Value;
            if value ~= 0 && app.n > 1
                if app.averagePLA ~= 0 || app.medianPLA ~= 0 || app.cellCount ~= 0
                    imshow(app.processedData{value}, 'parent', app.UIAxesImage)
                else
                    imgPLA = im2double(app.data{value}); %get image
                    imshow(imgPLA, 'parent', app.UIAxesImage)
                end
            end
        end

        % Button pushed function: ButtonStart
        function ButtonStartPushed(app, event)
            app.processedData = struct([]); %memory allocation
            blurMaskGauss = [1 2 4 2 1
                             2 4 8 4 2
                             4 8 16 8 4
                             2 4 8 4 2
                             1 2 4 2 1]/100; %gaussian blur mask
             
            % blurMaskGauss = [1 2 1
            %                  2 4 2
            %                  1 2 1]/16;
                         
            blurMaskAverage = ones(5,5)/25; %average blur mask

            if app.RedPLAButton.Value == 1
                app.PLAChannel = 1;
            elseif app.GreenPLAButton.Value == 1
                app.PLAChannel = 2;
            else
                app.PLAChannel = 3;
            end

            if app.RedNucleiButton.Value == 1
                app.nucleiChannel = 1;
            elseif app.GreenNucleiButton.Value == 1
                app.nucleiChannel = 2;
            else
                app.nucleiChannel = 3;
            end

            for i = 1:app.n
                if app.n == 1
                    imgPLA = im2double(app.data); %get image
                else
                    imgPLA = im2double(app.data{i}); %get image
                end
                REDch = imgPLA(:,:,app.PLAChannel); %pick PLA channel
                BLUEch = imgPLA(:,:,app.nucleiChannel); %pick nuclei channel

                %% Gaussian blur
                REDch = medfilt2(REDch);
                REDch = conv2(REDch, blurMaskGauss, 'same'); %convolution with gaussian blur mask
                BLUEch = conv2(BLUEch, blurMaskAverage, 'same'); %convolution with average blur mask

                %% Normalization [0-1]
                REDch = REDch-min(min(REDch));
                REDch = REDch./max(max(REDch));
                BLUEch = BLUEch-min(min(BLUEch));
                BLUEch = BLUEch./max(max(BLUEch));

                %% Obtaining DAPI mask
                otsuDapiThresh = graythresh(BLUEch)/2; %global threshold as a half of optimal Otsu threshold
                maskDAPI = im2bw(BLUEch, otsuDapiThresh); %thresholding
                maskDAPI = imfill(maskDAPI,'holes'); %filling holes
                maskDAPI = imopen(maskDAPI, strel('disk', 5)); %opening image
                maskDAPI = imclearborder(maskDAPI); %removing nuclei that intersect with the image edge
                maskDAPI = bwareaopen(maskDAPI, length(maskDAPI(:,1))); %select only regions with more pixels than width of the image

                %% Obtaining PLA signal only from nucleus
                PLAsignal = REDch.*maskDAPI; %multiply the DAPI mask with RED channel
                plaThresh = mean(mean(PLAsignal(maskDAPI))) + graythresh(PLAsignal(maskDAPI)); %mean value + otsu of PLA signal within the nuclei
                PLAsignal = REDch.*(im2bw(PLAsignal, plaThresh)); %subtract the background with plaThresh
                localMaxima = imregionalmax(PLAsignal, 8); %obtain local maxima

                %% Watershed segmentation
                inverseGradientMap = imhmin(1-bwdist(~maskDAPI), 6); %distance transform of binary image (negative nucleus) and supressing of regional minima
                segmentedRegions = watershed(inverseGradientMap); %watershed segmentation

                %% Count PLA signals for each nucleus
                nucleiCount = max(max(segmentedRegions)); %count number of regions in segmented image
                totalCountPLA = zeros(nucleiCount,1); %allocate PLA count
                
                for j = 1:nucleiCount %for each nucleus
                    selectedRegion = zeros(size(maskDAPI)); %allocate region
                    selectedRegion(segmentedRegions == j) = 1; %pick only one region
                    selectedRegion = (selectedRegion + maskDAPI) - 1; %add maskDAPI and subtract 1
                    selectedRegion(selectedRegion < 0) = 0; %each pixel below 0 assign to 0
                    PLAfoci = selectedRegion.*localMaxima; %multiply the region with localMaxima to obtain only PLA foci from one nucleus
                    PLACount = bwconncomp(PLAfoci);
                    totalCountPLA(j,1) = PLACount.NumObjects; %store the number
                end
                app.PLAdata{i} = totalCountPLA; %store the amount of PLA foci to the database
                app.vecPLA = [app.vecPLA totalCountPLA'];

                %% Overlay of DAPI and PLA signal with segmented regions
                nucleusSegmentedImage = zeros(size(imgPLA));
                nucleusSegmentedImage(:,:,3) = maskDAPI.*BLUEch;
                nucleusSegmentedImage(:,:,2) = segmentedRegions == 0;
                nucleusSegmentedImage(:,:,2) = nucleusSegmentedImage(:,:,2) + localMaxima;
                nucleusSegmentedImage(:,:,1) = REDch;
                app.processedData{i} = nucleusSegmentedImage;
            end

             %% Statistics
             app.averagePLA = double(sum(app.vecPLA))/double(length(app.vecPLA)); %count average number of foci per nucleus
             app.AverageEditField.Value = app.averagePLA; %display average result
             app.medianPLA = median(app.vecPLA); %count median number of PLA foci per nucleus
             app.MedianEditField.Value = app.medianPLA; %display median result
             app.cellCount = length(app.vecPLA); %count amount of analyzed cells
             app.NrcellsEditField.Value = app.cellCount; %display cells count result

             app.NrSpinnerImages.Value = 1;
             imshow(app.processedData{1}, 'parent', app.UIAxesImage); %display first processed image
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesImage = uiaxes(app.UIFigure);
            app.LabelPLACount = uilabel(app.UIFigure);
            app.LabelPLACount.Text = 'PLA count';
            app.AdvancedmodeSwitchLabel = uilabel(app.UIFigure);
            app.AdvancedmodeSwitchLabel.Text = 'Advanced mode';
            app.SwitchAdvancedMode = uiswitch(app.UIFigure, 'slider');
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.NrcellsEditFieldLabel = uilabel(app.ResultsPanel);
            app.NrcellsEditFieldLabel.Text = 'Nr. cells';
            app.NrcellsEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.NrcellsEditField.Editable = 'off';
            app.MedianEditFieldLabel = uilabel(app.ResultsPanel);
            app.MedianEditFieldLabel.Text = 'Median:';
            app.MedianEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.MedianEditField.Editable = 'off';
            app.AverageEditFieldLabel = uilabel(app.ResultsPanel);
            app.AverageEditFieldLabel.Text = 'Average:';
            app.AverageEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.AverageEditField.Editable = 'off';
            app.ImageNrSpinnerLabel = uilabel(app.UIFigure);
            app.ImageNrSpinnerLabel.Text = 'Image Nr.';
            app.NrSpinnerImages = uispinner(app.UIFigure);
            app.NrSpinnerImages.Limits = [0 Inf];
            app.NrSpinnerImages.ValueChangedFcn = createCallbackFcn(app, @NrSpinnerImagesValueChanged, true);
            app.ButtonLoadImages = uibutton(app.UIFigure, 'push');
            app.ButtonLoadImages.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadImagesPushed, true);
            app.ButtonLoadImages.Text = 'Load images';
            app.ButtonStart = uibutton(app.UIFigure, 'push');
            app.ButtonStart.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartPushed, true);
            app.ButtonStart.Text = 'START';
            app.ButtonExportData = uibutton(app.UIFigure, 'push');
            app.ButtonExportData.Text = 'Export data';
            app.Threshold1SliderLabel = uilabel(app.UIFigure);
            app.Threshold1SliderLabel.Text = 'Threshold 1';
            app.Threshold1Slider = uislider(app.UIFigure);
            app.Threshold1Slider.Limits = [0 1];
            app.Threshold2SliderLabel = uilabel(app.UIFigure);
            app.Threshold2SliderLabel.Text = 'Threshold 2';
            app.Threshold2Slider = uislider(app.UIFigure);
            app.Threshold2Slider.Limits = [0 1];
            app.Threshold3SliderLabel = uilabel(app.UIFigure);
            app.Threshold3SliderLabel.Text = 'Threshold 3';
            app.Threshold3Slider = uislider(app.UIFigure);
            app.Threshold3Slider.Limits = [0 1];
            app.LabelNumberOfImages = uilabel(app.UIFigure);
            app.LabelNumberOfImages.Text = '0';
            app.LabelSlash = uilabel(app.UIFigure);
            app.LabelSlash.Text = '/';
            app.ButtonGroupPLAchannel = uibuttongroup(app.UIFigure);
            app.ButtonGroupPLAchannel.Title = 'PLA channel';
            app.RedPLAButton = uiradiobutton(app.ButtonGroupPLAchannel);
            app.RedPLAButton.Text = 'Red';
            app.RedPLAButton.Value = true;
            app.GreenPLAButton = uiradiobutton(app.ButtonGroupPLAchannel);
            app.GreenPLAButton.Text = 'Green';
            app.BluePLAButton = uiradiobutton(app.ButtonGroupPLAchannel);
            app.BluePLAButton.Text = 'Blue';
            app.ButtonGroupNucleichannel = uibuttongroup(app.UIFigure);
            app.ButtonGroupNucleichannel.Title = 'Nuclei channel';
            app.RedNucleiButton = uiradiobutton(app.ButtonGroupNucleichannel);
            app.RedNucleiButton.Text = 'Red';
            app.GreenNucleiButton = uiradiobutton(app.ButtonGroupNucleichannel);
            app.GreenNucleiButton.Text = 'Green';
            app.BlueNucleiButton = uiradiobutton(app.ButtonGroupNucleichannel);
            app.BlueNucleiButton.Text = 'Blue';
            app.BlueNucleiButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PLAcountApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end